classdef TrimDiagramApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        AircraftsTab                    matlab.ui.container.Tab
        Boeing747lowcruiseButton        matlab.ui.control.Button
        DataPanel                       matlab.ui.container.Panel
        CmzeroEditFieldLabel            matlab.ui.control.Label
        CmzeroEditField                 matlab.ui.control.NumericEditField
        ClzeroEditFieldLabel            matlab.ui.control.Label
        ClzeroEditField                 matlab.ui.control.NumericEditField
        ClEditFieldLabel                matlab.ui.control.Label
        ClEditField                     matlab.ui.control.NumericEditField
        CmEditFieldLabel                matlab.ui.control.Label
        CmEditField                     matlab.ui.control.NumericEditField
        CliHEditFieldLabel              matlab.ui.control.Label
        CliHEditField                   matlab.ui.control.NumericEditField
        CmiHEditFieldLabel              matlab.ui.control.Label
        CmiHEditField                   matlab.ui.control.NumericEditField
        CldeltaelevatorEditFieldLabel   matlab.ui.control.Label
        CldeltaelevatorEditField        matlab.ui.control.NumericEditField
        CmdeltaelevatorEditFieldLabel   matlab.ui.control.Label
        CmdeltaelevatorEditField        matlab.ui.control.NumericEditField
        iHEditFieldLabel                matlab.ui.control.Label
        iHEditField                     matlab.ui.control.NumericEditField
        XcgEditFieldLabel               matlab.ui.control.Label
        XcgEditField                    matlab.ui.control.NumericEditField
        SIAIMarchettiS211lowcruiseButton  matlab.ui.control.Button
        SIAIMarchettiS211highcruiseButton  matlab.ui.control.Button
        McdonnellDouglasF4supersoniccruiseButton  matlab.ui.control.Button
        McdonnellDouglasF4subsoniccruiseButton  matlab.ui.control.Button
        LockheedF104cruiseButton        matlab.ui.control.Button
        LearJetMaxweightcruiseButton    matlab.ui.control.Button
        LearJetLowweightcruiseButton    matlab.ui.control.Button
        CessnaT37AcruiseButton          matlab.ui.control.Button
        Cessna620cruiseButton           matlab.ui.control.Button
        Cessna310cruiseButton           matlab.ui.control.Button
        Cessna182cruiseButton           matlab.ui.control.Button
        Boeing747highcruiseButton       matlab.ui.control.Button
        Beech99lowcruiseButton          matlab.ui.control.Button
        Beech99highcruiseButton         matlab.ui.control.Button
        Image2                          matlab.ui.control.Image
        Image2_2                        matlab.ui.control.Image
        Image2_3                        matlab.ui.control.Image
        Image2_4                        matlab.ui.control.Image
        Image2_5                        matlab.ui.control.Image
        Image2_6                        matlab.ui.control.Image
        Image2_7                        matlab.ui.control.Image
        Image2_8                        matlab.ui.control.Image
        Image2_9                        matlab.ui.control.Image
        Image2_10                       matlab.ui.control.Image
        Image2_11                       matlab.ui.control.Image
        ContactUsPanel                  matlab.ui.container.Panel
        alibaniasad1999yahoocomLabel    matlab.ui.control.Label
        TrimDiagramTab                  matlab.ui.container.Tab
        FigurePanel                     matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        ControlCardsPanel               matlab.ui.container.Panel
        Image                           matlab.ui.control.Image
        mostaftCGSwitchLabel            matlab.ui.control.Label
        mostaftCGSwitch                 matlab.ui.control.Switch
        mostforwardCGSwitchLabel        matlab.ui.control.Label
        mostforwardCGSwitch             matlab.ui.control.Switch
        CGforwardKnobLabel              matlab.ui.control.Label
        CGforwardKnob                   matlab.ui.control.Knob
        CGaftKnobLabel                  matlab.ui.control.Label
        CGaftKnob                       matlab.ui.control.Knob
        CGpositionSliderLabel           matlab.ui.control.Label
        CGpositionSlider                matlab.ui.control.Slider
        Image11                         matlab.ui.control.Image
        Tab                             matlab.ui.container.Tab
        Boeing747highcruiseCheckBox     matlab.ui.control.CheckBox
        Boeing747lowcruiseCheckBox      matlab.ui.control.CheckBox
        SIAIMarchettiS211lowcruiseCheckBox  matlab.ui.control.CheckBox
        SIAIMarchettiS211highcruiseCheckBox  matlab.ui.control.CheckBox
        McdonnellDouglasF4subsoniccruiseCheckBox  matlab.ui.control.CheckBox
        McdonnellDouglasF4supersoniccruiseCheckBox  matlab.ui.control.CheckBox
        LockheedF104cruiseCheckBox      matlab.ui.control.CheckBox
        LearJetMaxweightcruiseCheckBox  matlab.ui.control.CheckBox
        LearJetLowweightcruiseCheckBox  matlab.ui.control.CheckBox
        Cessna182cruiseButton_2         matlab.ui.control.Button
        Beech99lowcruiseButton_2        matlab.ui.control.Button
        Beech99highcruiseButton_2       matlab.ui.control.Button
        CessnaT37AcruiseCheckBox        matlab.ui.control.CheckBox
        Cessna620cruiseCheckBox         matlab.ui.control.CheckBox
        Cessna310cruiseCheckBox         matlab.ui.control.CheckBox
        Cessna182cruiseCheckBox         matlab.ui.control.CheckBox
        Beech99lowcruiseCheckBox        matlab.ui.control.CheckBox
        Beech99highcruiseCheckBox       matlab.ui.control.CheckBox
    end

    
    methods (Access = private)
                
        function TrimCurveInit(app)
            app.CmzeroEditField.Value = 0; % distance from real cg , delta_x_cg = now cg - cg
        end
        function TrimPloter(app)
            %%% iH %%%
            iH = app.iHEditField.Value;
            %%% CL elevator %%%
            Cl_delta_elevator = app.CldeltaelevatorEditField.Value;
            %%% Cm elevator %%%
            Cm_delta_elevator = app.CmdeltaelevatorEditField.Value;
            %%% Cl zero %%%
            Cl_zero = app.ClzeroEditField.Value;
            %%% Cm zero %%%
            Cm_zero = app.CmzeroEditField.Value;
            %%% Cl alpha %%%
            Cl_alpha = app.ClEditField.Value; % Rad
            %%% Cm alpha %%%
            Cm_alpha = app.CmEditField.Value;
            %%% Cl iH %%%
            Cl_iH = app.CliHEditField.Value; % Rad
            %%% Cm iH %%%
            Cm_iH = app.CmiHEditField.Value;
            %%% Cm_alpha_cl_alpha %%%
            Cm_alpha_cl_alph = (Cm_alpha / Cl_alpha) +...
                (app.CGpositionSlider.Value - app.XcgEditField.Value);
            %%% Cm zero bar %%%
            Cm_zero_bar = Cm_zero - Cm_alpha_cl_alph * Cl_zero;
            %%% Cm delta elevator bar %%%
            Cm_delta_elevator_bar = Cm_delta_elevator - Cm_alpha_cl_alph * Cl_delta_elevator;
            %%% Cm iH bar %%%
            Cm_iH_bar = Cm_iH - Cm_alpha_cl_alph * Cl_iH;
            %%% x_cg_bar
            %x_cg = app.XcgEditField.Value;
            delta_elevator = -30:5:15; %deg
            delta_elevator = delta_elevator * pi / 180; %rad
            Cl = 0:.1:3;
            hold(app.UIAxes,"off");
            for i = 1:length(delta_elevator)
                Cm = Cm_zero_bar + Cm_alpha_cl_alph * Cl +...
                Cm_delta_elevator_bar * delta_elevator(i) + Cm_iH_bar*iH;
                plot(app.UIAxes,Cm,Cl)
                hold(app.UIAxes,"on");
            end
            set(app.UIAxes, 'xdir', 'reverse' );
            xlabel(app.UIAxes, '$C_m$','Interpreter','latex');
            ylabel(app.UIAxes, '$C_l$','Interpreter','latex');
            legend(app.UIAxes, '$\delta_e = -30^{\circ}$','$\delta_e = -25^{\circ}$',...
        '$\delta_e = -20^{\circ}$','$\delta_e = -15^{\circ}$','$\delta_e = -10^{\circ}$','$\delta_e = -5^{\circ}$',...
        '$\delta_e = 0^{\circ}$','$\delta_e = 5^{\circ}$',...
        '$\delta_e = 10^{\circ}$','$\delta_e = 15^{\circ}$',...
        'Interpreter','latex')
        end
        function Trimploter_cg(app)
            %%% iH %%%
            iH = app.iHEditField.Value;
            %%% CL elevator %%%
            Cl_delta_elevator = app.CldeltaelevatorEditField.Value;
            %%% Cm elevator %%%
            Cm_delta_elevator = app.CmdeltaelevatorEditField.Value;
            %%% Cl zero %%%
            Cl_zero = app.ClzeroEditField.Value;
            %%% Cm zero %%%
            Cm_zero = app.CmzeroEditField.Value;
            %%% Cl alpha %%%
            Cl_alpha = app.ClEditField.Value; % Rad
            %%% Cm alpha %%%
            Cm_alpha = app.CmEditField.Value;
            %%% Cl iH %%%
            Cl_iH = app.CliHEditField.Value; % Rad
            %%% Cm iH %%%
            Cm_iH = app.CmiHEditField.Value;
            %%% Cm_alpha_cl_alpha %%%
            Cm_alpha_cl_alph = (Cm_alpha / Cl_alpha);
            %%% Cm zero bar %%%
            Cm_zero_bar = Cm_zero - Cm_alpha_cl_alph * Cl_zero;
            %%% Cm delta elevator bar %%%
            Cm_delta_elevator_bar = Cm_delta_elevator - Cm_alpha_cl_alph * Cl_delta_elevator;
            %%% Cm iH bar %%%
            Cm_iH_bar = Cm_iH - Cm_alpha_cl_alph * Cl_iH;
            delta_elevator = -30:5:15; %deg
            delta_elevator = delta_elevator * pi / 180; %rad
            %%% x_cg_bar
            x_cg = app.XcgEditField.Value;
            Cl = 0:.1:3;
            hold(app.UIAxes_2,"off");
            for i = 1:length(delta_elevator)
                Cm = Cm_zero_bar + (Cm_alpha / Cl_alpha) * Cl +...
                Cm_delta_elevator_bar * delta_elevator(i) + Cm_iH_bar*iH;
                plot(app.UIAxes_2, Cm,Cl)
                hold(app.UIAxes_2, "on");
            end
            x_axis = -10:.1:10;
            if app.CGpositionSlider.Value == 0
                plot(app.UIAxes_2, zeros(length(-1:10)), -1:10, 'k');
            else
                y1 = 1 / (x_cg - app.CGpositionSlider.Value) * x_axis;
                plot(app.UIAxes_2, x_axis, y1, 'k', 'linewidth', 2);
            end
            set(app.UIAxes_2, 'xdir', 'reverse' );
            xlabel(app.UIAxes_2, '$C_m$','Interpreter','latex');
            ylabel(app.UIAxes_2, '$C_l$','Interpreter','latex');
            if app.mostaftCGSwitch.Value == "On"
                x_axis = -10:.1:10;
                y1 = 1 / (x_cg - app.CGaftKnob.Value) * x_axis;
                plot(app.UIAxes_2, x_axis, y1, 'r:', 'linewidth', 2);
            end
            if app.mostforwardCGSwitch.Value == "On"
                x_axis = -10:.1:10;
                y1 = 1 / (x_cg - app.CGforwardKnob.Value) * x_axis;
                plot(app.UIAxes_2, x_axis, y1, 'r-', 'linewidth', 2);
            end
            axis(app.UIAxes_2, [-1 1 0 1])
        end
        function vis(app)
            app.mostforwardCGSwitch;
        end
        function off_all_box(app)
            app.Boeing747highcruiseCheckBox.Value = 0;
            app.Boeing747lowcruiseCheckBox.Value = 0;
            app.SIAIMarchettiS211highcruiseCheckBox.Value = 0;
            app.SIAIMarchettiS211lowcruiseCheckBox.Value = 0;
            app.McdonnellDouglasF4subsoniccruiseCheckBox.Value = 0;
            app.McdonnellDouglasF4supersoniccruiseCheckBox.Value = 0;
            app.LockheedF104cruiseCheckBox.Value = 0;
            app.LearJetLowweightcruiseCheckBox.Value = 0;
            app.LearJetMaxweightcruiseCheckBox.Value = 0;
            app.Cessna182cruiseCheckBox.Value = 0;
            app.Cessna310cruiseCheckBox.Value = 0;
            app.Cessna620cruiseCheckBox.Value = 0;
            app.Beech99highcruiseCheckBox.Value = 0;
            app.Beech99lowcruiseCheckBox.Value = 0;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if app.mostforwardCGSwitch.Value == "Off"
                app.CGforwardKnob.Visible = "off";
                app.CGforwardKnobLabel.Visible = "off";
            end
            if app.mostaftCGSwitch.Value == "Off"
                app.CGaftKnob.Visible = "off";
                app.CGaftKnobLabel.Visible = "off";
            end
        end

        % Button pushed function: Boeing747lowcruiseButton
        function Boeing747lowcruiseButtonPushed(app, event)
            seven_four_seven_low_cruise;
            app.ClzeroEditField.Value          = Cl_zero;
            app.CmzeroEditField.Value          = Cm_zero;
            app.ClEditField.Value              = Cl_alpha;
            app.CmEditField.Value              = Cm_alpha;
            app.CliHEditField.Value            = Cl_iH;
            app.CmiHEditField.Value            = Cm_iH;
            app.CldeltaelevatorEditField.Value = Cl_delta_elevator;
            app.CmdeltaelevatorEditField.Value = Cm_delta_elevator;
            app.iHEditField.Value              = iH;
            app.XcgEditField.Value             = x_cg;
            app.TrimPloter;
            app.Trimploter_cg;
            app.CGpositionSlider.Value = x_cg;
        end

        % Value changed function: CmzeroEditField
        function CmzeroEditFieldValueChanged(app, event)
            
        end

        % Size changed function: DataPanel
        function DataPanelSizeChanged(app, event)
            
        end

        % Value changed function: CGpositionSlider
        function CGpositionSliderValueChanged(app, event)
            app.TrimPloter;
            app.Trimploter_cg;
        end

        % Value changed function: CGforwardKnob
        function CGforwardKnobValueChanged(app, event)
            app.Trimploter_cg;
        end

        % Value changing function: CGforwardKnob
        function CGforwardKnobValueChanging(app, event)
            
        end

        % Value changed function: mostforwardCGSwitch
        function mostforwardCGSwitchValueChanged(app, event)
            value = app.mostforwardCGSwitch.Value;
            if value == "Off"
                app.CGforwardKnob.Visible = "off";
                app.CGforwardKnobLabel.Visible = "off";
            else
                app.CGforwardKnob.Visible = "on";
                app.CGforwardKnobLabel.Visible = "on";
            end
            app.Trimploter_cg;
        end

        % Value changed function: mostaftCGSwitch
        function mostaftCGSwitchValueChanged(app, event)
            value = app.mostaftCGSwitch.Value;
            if value == "Off"
                app.CGaftKnob.Visible = "off";
                app.CGaftKnobLabel.Visible = "off";
            else
                app.CGaftKnob.Visible = "on";
                app.CGaftKnobLabel.Visible = "on";
            end
            app.Trimploter_cg;
        end

        % Value changing function: CGpositionSlider
        function CGpositionSliderValueChanging(app, event)
            
        end

        % Value changed function: CGaftKnob
        function CGaftKnobValueChanged(app, event)
            app.Trimploter_cg;
        end

        % Button pushed function: SIAIMarchettiS211lowcruiseButton
        function SIAIMarchettiS211lowcruiseButtonPushed(app, event)
            SIAI_MarchettiS211_low_cruise;
            app.ClzeroEditField.Value          = Cl_zero;
            app.CmzeroEditField.Value          = Cm_zero;
            app.ClEditField.Value              = Cl_alpha;
            app.CmEditField.Value              = Cm_alpha;
            app.CliHEditField.Value            = Cl_iH;
            app.CmiHEditField.Value            = Cm_iH;
            app.CldeltaelevatorEditField.Value = Cl_delta_elevator;
            app.CmdeltaelevatorEditField.Value = Cm_delta_elevator;
            app.iHEditField.Value              = iH;
            app.XcgEditField.Value             = x_cg;
            app.TrimPloter;
            app.Trimploter_cg;
            app.CGpositionSlider.Value = x_cg;
        end

        % Button pushed function: SIAIMarchettiS211highcruiseButton
        function SIAIMarchettiS211highcruiseButtonPushed(app, event)
            SIAI_MarchettiS211_high_cruise;
            app.ClzeroEditField.Value          = Cl_zero;
            app.CmzeroEditField.Value          = Cm_zero;
            app.ClEditField.Value              = Cl_alpha;
            app.CmEditField.Value              = Cm_alpha;
            app.CliHEditField.Value            = Cl_iH;
            app.CmiHEditField.Value            = Cm_iH;
            app.CldeltaelevatorEditField.Value = Cl_delta_elevator;
            app.CmdeltaelevatorEditField.Value = Cm_delta_elevator;
            app.iHEditField.Value              = iH;
            app.XcgEditField.Value             = x_cg;
            app.TrimPloter;
            app.Trimploter_cg;
            app.CGpositionSlider.Value = x_cg;
        end

        % Button pushed function: 
        % McdonnellDouglasF4supersoniccruiseButton
        function McdonnellDouglasF4supersoniccruiseButtonPushed(app, event)
            Mcdonnell_Douglas_F4_supersonic_cruise;
            app.ClzeroEditField.Value          = Cl_zero;
            app.CmzeroEditField.Value          = Cm_zero;
            app.ClEditField.Value              = Cl_alpha;
            app.CmEditField.Value              = Cm_alpha;
            app.CliHEditField.Value            = Cl_iH;
            app.CmiHEditField.Value            = Cm_iH;
            app.CldeltaelevatorEditField.Value = Cl_delta_elevator;
            app.CmdeltaelevatorEditField.Value = Cm_delta_elevator;
            app.iHEditField.Value              = iH;
            app.XcgEditField.Value             = x_cg;
            app.TrimPloter;
            app.Trimploter_cg;
            app.CGpositionSlider.Value = x_cg;
        end

        % Button pushed function: 
        % McdonnellDouglasF4subsoniccruiseButton
        function McdonnellDouglasF4subsoniccruiseButtonPushed(app, event)
            Mcdonnell_Douglas_F4_subsonic_cruise;
            app.ClzeroEditField.Value          = Cl_zero;
            app.CmzeroEditField.Value          = Cm_zero;
            app.ClEditField.Value              = Cl_alpha;
            app.CmEditField.Value              = Cm_alpha;
            app.CliHEditField.Value            = Cl_iH;
            app.CmiHEditField.Value            = Cm_iH;
            app.CldeltaelevatorEditField.Value = Cl_delta_elevator;
            app.CmdeltaelevatorEditField.Value = Cm_delta_elevator;
            app.iHEditField.Value              = iH;
            app.XcgEditField.Value             = x_cg;
            app.TrimPloter;
            app.Trimploter_cg;
            app.CGpositionSlider.Value = x_cg;
        end

        % Button pushed function: LockheedF104cruiseButton
        function LockheedF104cruiseButtonPushed(app, event)
            Lockheed_F104_cruise;
            app.ClzeroEditField.Value          = Cl_zero;
            app.CmzeroEditField.Value          = Cm_zero;
            app.ClEditField.Value              = Cl_alpha;
            app.CmEditField.Value              = Cm_alpha;
            app.CliHEditField.Value            = Cl_iH;
            app.CmiHEditField.Value            = Cm_iH;
            app.CldeltaelevatorEditField.Value = Cl_delta_elevator;
            app.CmdeltaelevatorEditField.Value = Cm_delta_elevator;
            app.iHEditField.Value              = iH;
            app.XcgEditField.Value             = x_cg;
            app.TrimPloter;
            app.Trimploter_cg;
            app.CGpositionSlider.Value = x_cg;
        end

        % Button pushed function: LearJetMaxweightcruiseButton
        function LearJetMaxweightcruiseButtonPushed(app, event)
            LearJet_Max_weight_cruise;
            app.ClzeroEditField.Value          = Cl_zero;
            app.CmzeroEditField.Value          = Cm_zero;
            app.ClEditField.Value              = Cl_alpha;
            app.CmEditField.Value              = Cm_alpha;
            app.CliHEditField.Value            = Cl_iH;
            app.CmiHEditField.Value            = Cm_iH;
            app.CldeltaelevatorEditField.Value = Cl_delta_elevator;
            app.CmdeltaelevatorEditField.Value = Cm_delta_elevator;
            app.iHEditField.Value              = iH;
            app.XcgEditField.Value             = x_cg;
            app.TrimPloter;
            app.Trimploter_cg;
            app.CGpositionSlider.Value = x_cg;
        end

        % Button pushed function: LearJetLowweightcruiseButton
        function LearJetLowweightcruiseButtonPushed(app, event)
            LearJet_Low_weight_cruise;
            app.ClzeroEditField.Value          = Cl_zero;
            app.CmzeroEditField.Value          = Cm_zero;
            app.ClEditField.Value              = Cl_alpha;
            app.CmEditField.Value              = Cm_alpha;
            app.CliHEditField.Value            = Cl_iH;
            app.CmiHEditField.Value            = Cm_iH;
            app.CldeltaelevatorEditField.Value = Cl_delta_elevator;
            app.CmdeltaelevatorEditField.Value = Cm_delta_elevator;
            app.iHEditField.Value              = iH;
            app.XcgEditField.Value             = x_cg;
            app.TrimPloter;
            app.Trimploter_cg;
            app.CGpositionSlider.Value = x_cg;
        end

        % Button pushed function: CessnaT37AcruiseButton
        function CessnaT37AcruiseButtonPushed(app, event)
            Cessna_T37_A_cruise;
            app.ClzeroEditField.Value          = Cl_zero;
            app.CmzeroEditField.Value          = Cm_zero;
            app.ClEditField.Value              = Cl_alpha;
            app.CmEditField.Value              = Cm_alpha;
            app.CliHEditField.Value            = Cl_iH;
            app.CmiHEditField.Value            = Cm_iH;
            app.CldeltaelevatorEditField.Value = Cl_delta_elevator;
            app.CmdeltaelevatorEditField.Value = Cm_delta_elevator;
            app.iHEditField.Value              = iH;
            app.XcgEditField.Value             = x_cg;
            app.TrimPloter;
            app.Trimploter_cg;
            app.CGpositionSlider.Value = x_cg;
        end

        % Button pushed function: Cessna620cruiseButton
        function Cessna620cruiseButtonPushed(app, event)
            Cessna_620_cruise;
            app.ClzeroEditField.Value          = Cl_zero;
            app.CmzeroEditField.Value          = Cm_zero;
            app.ClEditField.Value              = Cl_alpha;
            app.CmEditField.Value              = Cm_alpha;
            app.CliHEditField.Value            = Cl_iH;
            app.CmiHEditField.Value            = Cm_iH;
            app.CldeltaelevatorEditField.Value = Cl_delta_elevator;
            app.CmdeltaelevatorEditField.Value = Cm_delta_elevator;
            app.iHEditField.Value              = iH;
            app.XcgEditField.Value             = x_cg;
            app.TrimPloter;
            app.Trimploter_cg;
            app.CGpositionSlider.Value = x_cg;
        end

        % Button pushed function: Cessna310cruiseButton
        function Cessna310cruiseButtonPushed(app, event)
            Cessna_310_cruise;
            app.ClzeroEditField.Value          = Cl_zero;
            app.CmzeroEditField.Value          = Cm_zero;
            app.ClEditField.Value              = Cl_alpha;
            app.CmEditField.Value              = Cm_alpha;
            app.CliHEditField.Value            = Cl_iH;
            app.CmiHEditField.Value            = Cm_iH;
            app.CldeltaelevatorEditField.Value = Cl_delta_elevator;
            app.CmdeltaelevatorEditField.Value = Cm_delta_elevator;
            app.iHEditField.Value              = iH;
            app.XcgEditField.Value             = x_cg;
            app.TrimPloter;
            app.Trimploter_cg;
            app.CGpositionSlider.Value = x_cg;
        end

        % Button pushed function: Cessna182cruiseButton
        function Cessna182cruiseButtonPushed(app, event)
            Cessna_182_cruise;
            app.ClzeroEditField.Value          = Cl_zero;
            app.CmzeroEditField.Value          = Cm_zero;
            app.ClEditField.Value              = Cl_alpha;
            app.CmEditField.Value              = Cm_alpha;
            app.CliHEditField.Value            = Cl_iH;
            app.CmiHEditField.Value            = Cm_iH;
            app.CldeltaelevatorEditField.Value = Cl_delta_elevator;
            app.CmdeltaelevatorEditField.Value = Cm_delta_elevator;
            app.iHEditField.Value              = iH;
            app.XcgEditField.Value             = x_cg;
            app.TrimPloter;
            app.Trimploter_cg;
            app.CGpositionSlider.Value = x_cg;
        end

        % Button pushed function: Boeing747highcruiseButton
        function Boeing747highcruiseButtonPushed(app, event)
            Boeing_747_high_cruise;
            app.ClzeroEditField.Value          = Cl_zero;
            app.CmzeroEditField.Value          = Cm_zero;
            app.ClEditField.Value              = Cl_alpha;
            app.CmEditField.Value              = Cm_alpha;
            app.CliHEditField.Value            = Cl_iH;
            app.CmiHEditField.Value            = Cm_iH;
            app.CldeltaelevatorEditField.Value = Cl_delta_elevator;
            app.CmdeltaelevatorEditField.Value = Cm_delta_elevator;
            app.iHEditField.Value              = iH;
            app.XcgEditField.Value             = x_cg;
            app.TrimPloter;
            app.Trimploter_cg;
            app.CGpositionSlider.Value = x_cg;
        end

        % Button pushed function: Beech99lowcruiseButton
        function Beech99lowcruiseButtonPushed(app, event)
            Beech_99_low_cruise;
            app.ClzeroEditField.Value          = Cl_zero;
            app.CmzeroEditField.Value          = Cm_zero;
            app.ClEditField.Value              = Cl_alpha;
            app.CmEditField.Value              = Cm_alpha;
            app.CliHEditField.Value            = Cl_iH;
            app.CmiHEditField.Value            = Cm_iH;
            app.CldeltaelevatorEditField.Value = Cl_delta_elevator;
            app.CmdeltaelevatorEditField.Value = Cm_delta_elevator;
            app.iHEditField.Value              = iH;
            app.XcgEditField.Value             = x_cg;
            app.TrimPloter;
            app.Trimploter_cg;
            app.CGpositionSlider.Value = x_cg;
        end

        % Button pushed function: Beech99highcruiseButton
        function Beech99highcruiseButtonPushed(app, event)
            Beech_99_high_cruise;
            app.ClzeroEditField.Value          = Cl_zero;
            app.CmzeroEditField.Value          = Cm_zero;
            app.ClEditField.Value              = Cl_alpha;
            app.CmEditField.Value              = Cm_alpha;
            app.CliHEditField.Value            = Cl_iH;
            app.CmiHEditField.Value            = Cm_iH;
            app.CldeltaelevatorEditField.Value = Cl_delta_elevator;
            app.CmdeltaelevatorEditField.Value = Cm_delta_elevator;
            app.iHEditField.Value              = iH;
            app.XcgEditField.Value             = x_cg;
            app.TrimPloter;
            app.Trimploter_cg;
            app.CGpositionSlider.Value = x_cg;
        end

        % Value changed function: 
        % McdonnellDouglasF4supersoniccruiseCheckBox
        function McdonnellDouglasF4supersoniccruiseCheckBoxValueChanged(app, event)
            app.off_all_box;
            app.McdonnellDouglasF4supersoniccruiseCheckBox.Value = 1;
        end

        % Value changed function: Boeing747highcruiseCheckBox
        function Boeing747highcruiseCheckBoxValueChanged(app, event)
            app.off_all_box;
            app.Boeing747highcruiseCheckBox.Value = 1;
        end

        % Value changed function: Boeing747lowcruiseCheckBox
        function Boeing747lowcruiseCheckBoxValueChanged(app, event)
            app.off_all_box;
            app.Boeing747lowcruiseCheckBox.Value = 1;
        end

        % Value changed function: SIAIMarchettiS211lowcruiseCheckBox
        function SIAIMarchettiS211lowcruiseCheckBoxValueChanged(app, event)
            app.off_all_box;
            app.SIAIMarchettiS211lowcruiseCheckBox.Value = 1;
        end

        % Value changed function: 
        % SIAIMarchettiS211highcruiseCheckBox
        function SIAIMarchettiS211highcruiseCheckBoxValueChanged(app, event)
            app.off_all_box;
            app.SIAIMarchettiS211highcruiseCheckBox.Value = 1;
        end

        % Value changed function: LockheedF104cruiseCheckBox
        function LockheedF104cruiseCheckBoxValueChanged(app, event)
            app.off_all_box;
            app.LockheedF104cruiseCheckBox.Value = 1;
        end

        % Value changed function: LearJetMaxweightcruiseCheckBox
        function LearJetMaxweightcruiseCheckBoxValueChanged(app, event)
            app.off_all_box;
            app.LearJetMaxweightcruiseCheckBox.Value = 1;
        end

        % Value changed function: LearJetLowweightcruiseCheckBox
        function LearJetLowweightcruiseCheckBoxValueChanged(app, event)
            app.off_all_box;
            app.LearJetLowweightcruiseCheckBox.Value = 1;
        end

        % Value changed function: 
        % McdonnellDouglasF4subsoniccruiseCheckBox
        function McdonnellDouglasF4subsoniccruiseCheckBoxValueChanged(app, event)
            app.off_all_box;
            app.McdonnellDouglasF4subsoniccruiseCheckBox.Value = 1;
        end

        % Value changed function: CessnaT37AcruiseCheckBox
        function CessnaT37AcruiseCheckBoxValueChanged(app, event)
            app.off_all_box;
            app.CessnaT37AcruiseCheckBox.Value = 1;
        end

        % Value changed function: Cessna620cruiseCheckBox
        function Cessna620cruiseCheckBoxValueChanged(app, event)
            app.off_all_box;
            app.Cessna620cruiseCheckBox.Value = 1;
        end

        % Value changed function: Cessna310cruiseCheckBox
        function Cessna310cruiseCheckBoxValueChanged(app, event)
            app.off_all_box;
            app.Cessna310cruiseCheckBox.Value = 1;
        end

        % Value changed function: Cessna182cruiseCheckBox
        function Cessna182cruiseCheckBoxValueChanged(app, event)
            app.off_all_box;
            app.Cessna182cruiseCheckBox.Value = 1;
        end

        % Value changed function: Beech99lowcruiseCheckBox
        function Beech99lowcruiseCheckBoxValueChanged(app, event)
            app.off_all_box;
            app.Beech99lowcruiseCheckBox.Value = 1;
        end

        % Value changed function: Beech99highcruiseCheckBox
        function Beech99highcruiseCheckBoxValueChanged(app, event)
            app.off_all_box;
            app.Beech99highcruiseCheckBox.Value = 1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AircraftsTab = uitab(app.TabGroup);
            app.AircraftsTab.Title = 'Aircrafts';
            app.Boeing747lowcruiseButton = uibutton(app.AircraftsTab, 'push');
            app.Boeing747lowcruiseButton.ButtonPushedFcn = createCallbackFcn(app, @Boeing747lowcruiseButtonPushed, true);
            app.Boeing747lowcruiseButton.Text = 'Boeing 747 low cruise';
            app.DataPanel = uipanel(app.AircraftsTab);
            app.DataPanel.Title = 'Data';
            app.DataPanel.SizeChangedFcn = createCallbackFcn(app, @DataPanelSizeChanged, true);
            app.CmzeroEditFieldLabel = uilabel(app.DataPanel);
            app.CmzeroEditFieldLabel.Text = 'Cm zero';
            app.CmzeroEditField = uieditfield(app.DataPanel, 'numeric');
            app.CmzeroEditField.ValueChangedFcn = createCallbackFcn(app, @CmzeroEditFieldValueChanged, true);
            app.ClzeroEditFieldLabel = uilabel(app.DataPanel);
            app.ClzeroEditFieldLabel.Text = 'Cl zero';
            app.ClzeroEditField = uieditfield(app.DataPanel, 'numeric');
            app.ClEditFieldLabel = uilabel(app.DataPanel);
            app.ClEditFieldLabel.Text = 'Cl α';
            app.ClEditField = uieditfield(app.DataPanel, 'numeric');
            app.CmEditFieldLabel = uilabel(app.DataPanel);
            app.CmEditFieldLabel.Text = 'Cm α';
            app.CmEditField = uieditfield(app.DataPanel, 'numeric');
            app.CliHEditFieldLabel = uilabel(app.DataPanel);
            app.CliHEditFieldLabel.Text = 'Cl i H';
            app.CliHEditField = uieditfield(app.DataPanel, 'numeric');
            app.CmiHEditFieldLabel = uilabel(app.DataPanel);
            app.CmiHEditFieldLabel.Text = 'Cm i H';
            app.CmiHEditField = uieditfield(app.DataPanel, 'numeric');
            app.CldeltaelevatorEditFieldLabel = uilabel(app.DataPanel);
            app.CldeltaelevatorEditFieldLabel.Text = 'Cl delta elevator';
            app.CldeltaelevatorEditField = uieditfield(app.DataPanel, 'numeric');
            app.CmdeltaelevatorEditFieldLabel = uilabel(app.DataPanel);
            app.CmdeltaelevatorEditFieldLabel.Text = 'Cm delta elevator';
            app.CmdeltaelevatorEditField = uieditfield(app.DataPanel, 'numeric');
            app.iHEditFieldLabel = uilabel(app.DataPanel);
            app.iHEditFieldLabel.Text = 'i H';
            app.iHEditField = uieditfield(app.DataPanel, 'numeric');
            app.XcgEditFieldLabel = uilabel(app.DataPanel);
            app.XcgEditFieldLabel.Text = 'X cg';
            app.XcgEditField = uieditfield(app.DataPanel, 'numeric');
            app.SIAIMarchettiS211lowcruiseButton = uibutton(app.AircraftsTab, 'push');
            app.SIAIMarchettiS211lowcruiseButton.ButtonPushedFcn = createCallbackFcn(app, @SIAIMarchettiS211lowcruiseButtonPushed, true);
            app.SIAIMarchettiS211lowcruiseButton.Text = 'SIAI MarchettiS211 low cruise';
            app.SIAIMarchettiS211highcruiseButton = uibutton(app.AircraftsTab, 'push');
            app.SIAIMarchettiS211highcruiseButton.ButtonPushedFcn = createCallbackFcn(app, @SIAIMarchettiS211highcruiseButtonPushed, true);
            app.SIAIMarchettiS211highcruiseButton.Text = 'SIAI MarchettiS211 high cruise';
            app.McdonnellDouglasF4supersoniccruiseButton = uibutton(app.AircraftsTab, 'push');
            app.McdonnellDouglasF4supersoniccruiseButton.ButtonPushedFcn = createCallbackFcn(app, @McdonnellDouglasF4supersoniccruiseButtonPushed, true);
            app.McdonnellDouglasF4supersoniccruiseButton.Text = 'Mcdonnell Douglas F4 supersonic cruise';
            app.McdonnellDouglasF4subsoniccruiseButton = uibutton(app.AircraftsTab, 'push');
            app.McdonnellDouglasF4subsoniccruiseButton.ButtonPushedFcn = createCallbackFcn(app, @McdonnellDouglasF4subsoniccruiseButtonPushed, true);
            app.McdonnellDouglasF4subsoniccruiseButton.Text = 'Mcdonnell Douglas F4 subsonic cruise';
            app.LockheedF104cruiseButton = uibutton(app.AircraftsTab, 'push');
            app.LockheedF104cruiseButton.ButtonPushedFcn = createCallbackFcn(app, @LockheedF104cruiseButtonPushed, true);
            app.LockheedF104cruiseButton.Text = 'Lockheed F104 cruise';
            app.LearJetMaxweightcruiseButton = uibutton(app.AircraftsTab, 'push');
            app.LearJetMaxweightcruiseButton.ButtonPushedFcn = createCallbackFcn(app, @LearJetMaxweightcruiseButtonPushed, true);
            app.LearJetMaxweightcruiseButton.Text = 'LearJet Max weight cruise';
            app.LearJetLowweightcruiseButton = uibutton(app.AircraftsTab, 'push');
            app.LearJetLowweightcruiseButton.ButtonPushedFcn = createCallbackFcn(app, @LearJetLowweightcruiseButtonPushed, true);
            app.LearJetLowweightcruiseButton.Text = 'LearJet Low weight cruise';
            app.CessnaT37AcruiseButton = uibutton(app.AircraftsTab, 'push');
            app.CessnaT37AcruiseButton.ButtonPushedFcn = createCallbackFcn(app, @CessnaT37AcruiseButtonPushed, true);
            app.CessnaT37AcruiseButton.Text = 'Cessna T37 A cruise';
            app.Cessna620cruiseButton = uibutton(app.AircraftsTab, 'push');
            app.Cessna620cruiseButton.ButtonPushedFcn = createCallbackFcn(app, @Cessna620cruiseButtonPushed, true);
            app.Cessna620cruiseButton.Text = 'Cessna 620 cruise';
            app.Cessna310cruiseButton = uibutton(app.AircraftsTab, 'push');
            app.Cessna310cruiseButton.ButtonPushedFcn = createCallbackFcn(app, @Cessna310cruiseButtonPushed, true);
            app.Cessna310cruiseButton.Text = 'Cessna 310 cruise';
            app.Cessna182cruiseButton = uibutton(app.AircraftsTab, 'push');
            app.Cessna182cruiseButton.ButtonPushedFcn = createCallbackFcn(app, @Cessna182cruiseButtonPushed, true);
            app.Cessna182cruiseButton.Text = 'Cessna 182 cruise';
            app.Boeing747highcruiseButton = uibutton(app.AircraftsTab, 'push');
            app.Boeing747highcruiseButton.ButtonPushedFcn = createCallbackFcn(app, @Boeing747highcruiseButtonPushed, true);
            app.Boeing747highcruiseButton.Text = 'Boeing 747 high cruise';
            app.Beech99lowcruiseButton = uibutton(app.AircraftsTab, 'push');
            app.Beech99lowcruiseButton.ButtonPushedFcn = createCallbackFcn(app, @Beech99lowcruiseButtonPushed, true);
            app.Beech99lowcruiseButton.Text = 'Beech 99 low cruise';
            app.Beech99highcruiseButton = uibutton(app.AircraftsTab, 'push');
            app.Beech99highcruiseButton.ButtonPushedFcn = createCallbackFcn(app, @Beech99highcruiseButtonPushed, true);
            app.Beech99highcruiseButton.Text = 'Beech 99 high cruise';
            app.Image2 = uiimage(app.AircraftsTab);
            app.Image2.ImageSource = 'ea2d745a970680c7efd6f4a0cf6ba642df7a46d1_boeing-747-400-privatefly-aa1616.jpg';
            app.Image2_2 = uiimage(app.AircraftsTab);
            app.Image2_2.ImageSource = 'Privately_owned_S.211_N852TC_in_flight.jpg';
            app.Image2_3 = uiimage(app.AircraftsTab);
            app.Image2_3.ImageSource = 'QF-4_Holloman_AFB.jpg';
            app.Image2_4 = uiimage(app.AircraftsTab);
            app.Image2_4.ImageSource = '4UVG2DNI6REB3N46F7HABKSA5U.jpg';
            app.Image2_5 = uiimage(app.AircraftsTab);
            app.Image2_5.ImageSource = 'learjet0216.jpg';
            app.Image2_6 = uiimage(app.AircraftsTab);
            app.Image2_6.ImageSource = '2004p_310_001_16x9.jpg';
            app.Image2_7 = uiimage(app.AircraftsTab);
            app.Image2_7.ImageSource = 'images.png';
            app.Image2_8 = uiimage(app.AircraftsTab);
            app.Image2_8.ImageSource = 'T-37_021203-O-9999G-003.jpg';
            app.Image2_9 = uiimage(app.AircraftsTab);
            app.Image2_9.ImageSource = '03-333_146.jpg';
            app.Image2_10 = uiimage(app.AircraftsTab);
            app.Image2_10.ImageSource = 'Jamaica_Air_Shuttle_Beechcraft_Model_99_In_Flight.jpg';
            app.Image2_11 = uiimage(app.AircraftsTab);
            app.Image2_11.ImageSource = 'logo-en.png';
            app.ContactUsPanel = uipanel(app.AircraftsTab);
            app.ContactUsPanel.Title = 'Contact Us';
            app.alibaniasad1999yahoocomLabel = uilabel(app.ContactUsPanel);
            app.alibaniasad1999yahoocomLabel.Text = 'alibaniasad1999@yahoo.com';
            app.TrimDiagramTab = uitab(app.TabGroup);
            app.TrimDiagramTab.Title = 'Trim Diagram';
            app.FigurePanel = uipanel(app.TrimDiagramTab);
            app.FigurePanel.Title = 'Figure';
            app.UIAxes = uiaxes(app.FigurePanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.FigurePanel);
            title(app.UIAxes_2, 'x')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.ControlCardsPanel = uipanel(app.TrimDiagramTab);
            app.ControlCardsPanel.Title = 'Control Cards';
            app.Image = uiimage(app.ControlCardsPanel);
            app.Image.ImageSource = 'Screen Shot 1400-03-17 at 14.48.52.png';
            app.mostaftCGSwitchLabel = uilabel(app.ControlCardsPanel);
            app.mostaftCGSwitchLabel.Text = 'most aft CG';
            app.mostaftCGSwitch = uiswitch(app.ControlCardsPanel, 'slider');
            app.mostaftCGSwitch.ValueChangedFcn = createCallbackFcn(app, @mostaftCGSwitchValueChanged, true);
            app.mostforwardCGSwitchLabel = uilabel(app.ControlCardsPanel);
            app.mostforwardCGSwitchLabel.Text = 'most forward CG';
            app.mostforwardCGSwitch = uiswitch(app.ControlCardsPanel, 'slider');
            app.mostforwardCGSwitch.ValueChangedFcn = createCallbackFcn(app, @mostforwardCGSwitchValueChanged, true);
            app.CGforwardKnobLabel = uilabel(app.ControlCardsPanel);
            app.CGforwardKnobLabel.Text = 'CG forward';
            app.CGforwardKnob = uiknob(app.ControlCardsPanel, 'continuous');
            app.CGforwardKnob.Limits = [0 0.2];
            app.CGforwardKnob.ValueChangedFcn = createCallbackFcn(app, @CGforwardKnobValueChanged, true);
            app.CGforwardKnob.ValueChangingFcn = createCallbackFcn(app, @CGforwardKnobValueChanging, true);
            app.CGaftKnobLabel = uilabel(app.ControlCardsPanel);
            app.CGaftKnobLabel.Text = 'CG aft';
            app.CGaftKnob = uiknob(app.ControlCardsPanel, 'continuous');
            app.CGaftKnob.Limits = [0.7 0.9];
            app.CGaftKnob.MajorTicks = [0.7 0.75 0.8 0.85 0.9];
            app.CGaftKnob.ValueChangedFcn = createCallbackFcn(app, @CGaftKnobValueChanged, true);
            app.CGaftKnob.Value = 0.7;
            app.CGpositionSliderLabel = uilabel(app.ControlCardsPanel);
            app.CGpositionSliderLabel.Text = 'CG position';
            app.CGpositionSlider = uislider(app.ControlCardsPanel);
            app.CGpositionSlider.Limits = [0 1];
            app.CGpositionSlider.ValueChangedFcn = createCallbackFcn(app, @CGpositionSliderValueChanged, true);
            app.CGpositionSlider.ValueChangingFcn = createCallbackFcn(app, @CGpositionSliderValueChanging, true);
            app.Image11 = uiimage(app.ControlCardsPanel);
            app.Image11.ImageSource = 'logo-en.png';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.Boeing747highcruiseCheckBox = uicheckbox(app.Tab);
            app.Boeing747highcruiseCheckBox.ValueChangedFcn = createCallbackFcn(app, @Boeing747highcruiseCheckBoxValueChanged, true);
            app.Boeing747highcruiseCheckBox.Text = 'Boeing 747 high cruise';
            app.Boeing747lowcruiseCheckBox = uicheckbox(app.Tab);
            app.Boeing747lowcruiseCheckBox.ValueChangedFcn = createCallbackFcn(app, @Boeing747lowcruiseCheckBoxValueChanged, true);
            app.Boeing747lowcruiseCheckBox.Text = 'Boeing 747 low cruise';
            app.SIAIMarchettiS211lowcruiseCheckBox = uicheckbox(app.Tab);
            app.SIAIMarchettiS211lowcruiseCheckBox.ValueChangedFcn = createCallbackFcn(app, @SIAIMarchettiS211lowcruiseCheckBoxValueChanged, true);
            app.SIAIMarchettiS211lowcruiseCheckBox.Text = 'SIAI MarchettiS211 low cruise';
            app.SIAIMarchettiS211highcruiseCheckBox = uicheckbox(app.Tab);
            app.SIAIMarchettiS211highcruiseCheckBox.ValueChangedFcn = createCallbackFcn(app, @SIAIMarchettiS211highcruiseCheckBoxValueChanged, true);
            app.SIAIMarchettiS211highcruiseCheckBox.Text = 'SIAI MarchettiS211 high cruise';
            app.McdonnellDouglasF4subsoniccruiseCheckBox = uicheckbox(app.Tab);
            app.McdonnellDouglasF4subsoniccruiseCheckBox.ValueChangedFcn = createCallbackFcn(app, @McdonnellDouglasF4subsoniccruiseCheckBoxValueChanged, true);
            app.McdonnellDouglasF4subsoniccruiseCheckBox.Text = 'Mcdonnell Douglas F4 subsonic cruise';
            app.McdonnellDouglasF4supersoniccruiseCheckBox = uicheckbox(app.Tab);
            app.McdonnellDouglasF4supersoniccruiseCheckBox.ValueChangedFcn = createCallbackFcn(app, @McdonnellDouglasF4supersoniccruiseCheckBoxValueChanged, true);
            app.McdonnellDouglasF4supersoniccruiseCheckBox.Text = 'Mcdonnell Douglas F4 supersonic cruise';
            app.LockheedF104cruiseCheckBox = uicheckbox(app.Tab);
            app.LockheedF104cruiseCheckBox.ValueChangedFcn = createCallbackFcn(app, @LockheedF104cruiseCheckBoxValueChanged, true);
            app.LockheedF104cruiseCheckBox.Text = 'Lockheed F104 cruise';
            app.LearJetMaxweightcruiseCheckBox = uicheckbox(app.Tab);
            app.LearJetMaxweightcruiseCheckBox.ValueChangedFcn = createCallbackFcn(app, @LearJetMaxweightcruiseCheckBoxValueChanged, true);
            app.LearJetMaxweightcruiseCheckBox.Text = 'LearJet Max weight cruise';
            app.LearJetLowweightcruiseCheckBox = uicheckbox(app.Tab);
            app.LearJetLowweightcruiseCheckBox.ValueChangedFcn = createCallbackFcn(app, @LearJetLowweightcruiseCheckBoxValueChanged, true);
            app.LearJetLowweightcruiseCheckBox.Text = 'LearJet Low weight cruise';
            app.Cessna182cruiseButton_2 = uibutton(app.Tab, 'push');
            app.Cessna182cruiseButton_2.Text = 'Cessna 182 cruise';
            app.Beech99lowcruiseButton_2 = uibutton(app.Tab, 'push');
            app.Beech99lowcruiseButton_2.Text = 'Beech 99 low cruise';
            app.Beech99highcruiseButton_2 = uibutton(app.Tab, 'push');
            app.Beech99highcruiseButton_2.Text = 'Beech 99 high cruise';
            app.CessnaT37AcruiseCheckBox = uicheckbox(app.Tab);
            app.CessnaT37AcruiseCheckBox.ValueChangedFcn = createCallbackFcn(app, @CessnaT37AcruiseCheckBoxValueChanged, true);
            app.CessnaT37AcruiseCheckBox.Text = 'Cessna T37 A cruise';
            app.Cessna620cruiseCheckBox = uicheckbox(app.Tab);
            app.Cessna620cruiseCheckBox.ValueChangedFcn = createCallbackFcn(app, @Cessna620cruiseCheckBoxValueChanged, true);
            app.Cessna620cruiseCheckBox.Text = 'Cessna 620 cruise';
            app.Cessna310cruiseCheckBox = uicheckbox(app.Tab);
            app.Cessna310cruiseCheckBox.ValueChangedFcn = createCallbackFcn(app, @Cessna310cruiseCheckBoxValueChanged, true);
            app.Cessna310cruiseCheckBox.Text = 'Cessna 310 cruise';
            app.Cessna182cruiseCheckBox = uicheckbox(app.Tab);
            app.Cessna182cruiseCheckBox.ValueChangedFcn = createCallbackFcn(app, @Cessna182cruiseCheckBoxValueChanged, true);
            app.Cessna182cruiseCheckBox.Text = 'Cessna 182 cruise';
            app.Beech99lowcruiseCheckBox = uicheckbox(app.Tab);
            app.Beech99lowcruiseCheckBox.ValueChangedFcn = createCallbackFcn(app, @Beech99lowcruiseCheckBoxValueChanged, true);
            app.Beech99lowcruiseCheckBox.Text = 'Beech 99 low cruise';
            app.Beech99highcruiseCheckBox = uicheckbox(app.Tab);
            app.Beech99highcruiseCheckBox.ValueChangedFcn = createCallbackFcn(app, @Beech99highcruiseCheckBoxValueChanged, true);
            app.Beech99highcruiseCheckBox.Text = 'Beech 99 high cruise';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TrimDiagramApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end