classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FlightDynamicsEducationUIFigure  matlab.ui.Figure
        Menu                          matlab.ui.container.Menu
        TrimCurveMenu                 matlab.ui.container.Menu
        AboutMenu                     matlab.ui.container.Menu
        MainPanel                     matlab.ui.container.Panel
        Image                         matlab.ui.control.Image
        TrimcurvePanel                matlab.ui.container.Panel
        GridLayout                    matlab.ui.container.GridLayout
        ConfirmButton                 matlab.ui.control.Button
        Cm0Label                      matlab.ui.control.Label
        Cm0EditField                  matlab.ui.control.NumericEditField
        dCmdCLEditFieldLabel          matlab.ui.control.Label
        dCmdCLEditField               matlab.ui.control.NumericEditField
        MinDElevatorEditField_2Label  matlab.ui.control.Label
        MinDElevatorEditField_2       matlab.ui.control.NumericEditField
        MaxDElevatorEditFieldLabel    matlab.ui.control.Label
        MaxDElevatorEditField         matlab.ui.control.NumericEditField
        NumberofStepEditFieldLabel    matlab.ui.control.Label
        NumberofStepEditField         matlab.ui.control.NumericEditField
        CancelButton                  matlab.ui.control.Button
        XcgEditFieldLabel             matlab.ui.control.Label
        XcgEditField                  matlab.ui.control.NumericEditField
        CmDElevatorEditFieldLabel     matlab.ui.control.Label
        CmDElevatorEditField          matlab.ui.control.NumericEditField
        MaximumCLEditFieldLabel       matlab.ui.control.Label
        MaximumCLEditField            matlab.ui.control.NumericEditField
        TrimPloterPanel               matlab.ui.container.Panel
        FinishButton                  matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
        Image2                        matlab.ui.control.Image
        AerodynamicsCenterEditFieldLabel  matlab.ui.control.Label
        AerodynamicsCenterEditField   matlab.ui.control.EditField
        PresentCenterofGravityEditFieldLabel  matlab.ui.control.Label
        PresentCenterofGravityEditField  matlab.ui.control.EditField
        Slider                        matlab.ui.control.Slider
        MinimumCGEditFieldLabel       matlab.ui.control.Label
        MinimumCGEditField            matlab.ui.control.EditField
        AboutPanel                    matlab.ui.container.Panel
        TextArea                      matlab.ui.control.TextArea
        CancelButton_2                matlab.ui.control.Button
    end

    
    properties (Access = public)
        ac; % aerodynamic center
        min_cg; % minimum value of center of gravity
        current_cg;
        dcm_dcl;
    end


    methods (Access = private)
        
        function TrimCurveInit(app)
            app.PresentCenterofGravityEditField.Value = num2str(app.XcgEditField.Value);
            app.ac = app.XcgEditField.Value-app.dCmdCLEditField.Value; % calculate ac
            app.AerodynamicsCenterEditField.Value = num2str(app.ac);
            % calculate min cg
            cm_cl_new =(app.Cm0EditField.Value+(app.CmDElevatorEditField.Value*app.MinDElevatorEditField_2.Value))/(-1*app.MaximumCLEditField.Value);
            app.min_cg = cm_cl_new + app.ac;
            app.MinimumCGEditField.Value = num2str(app.min_cg);
            app.current_cg = app.XcgEditField.Value;
            app.dcm_dcl = app.dCmdCLEditField.Value;
            app.Slider.Value = 10*(app.current_cg - app.ac)/(app.min_cg-app.ac);
            drawPlot(app);
        end
                 
        
        function drawPlot(app)
            step = (app.MaxDElevatorEditField.Value-app.MinDElevatorEditField_2.Value)/app.NumberofStepEditField.Value;
            hold(app.UIAxes,"off");
            for i = 0:1:app.NumberofStepEditField.Value
                elevatorAngle = app.MinDElevatorEditField_2.Value+step*i;
                cl = [0,app.MaximumCLEditField.Value];
                cm = app.Cm0EditField.Value+(app.dcm_dcl*cl)+app.CmDElevatorEditField.Value*(elevatorAngle);
                set(app.UIAxes, 'xdir', 'reverse');
                plot(app.UIAxes,cm,cl,'DisplayName',strcat('Elevator: ',num2str(elevatorAngle)))
                hold(app.UIAxes,"on");
            end
            lgd = legend(app.UIAxes);
            lgd.FontSize = 10;
            lgd.NumColumns = 2;
            lgd.Location='north'  ;                 
        end
    end
  

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: TrimCurveMenu
        function TrimCurveMenuSelected(app, event)
            app.TrimcurvePanel.Visible = 'on';
            
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            app.TrimcurvePanel.Visible = 'off';
            TrimCurveInit(app);
            app.TrimPloterPanel.Visible = 'on';
            
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
             app.TrimcurvePanel.Visible = 'off';
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            app.PresentCenterofGravityEditField.Value = num2str(app.ac+(changingValue/10)*(app.min_cg-app.ac),'%.3f');
            
        end

        % Button pushed function: FinishButton
        function FinishButtonPushed(app, event)
            app.TrimPloterPanel.Visible = 'off';
            app.TrimcurvePanel.Visible = 'on';
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.ac+(app.Slider.Value/10)*(app.min_cg-app.ac) ;
            app.dcm_dcl = app.dcm_dcl -(app.current_cg-value);
            app.current_cg = value;
            drawPlot(app);
                  
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            app.AboutPanel.Visible = 'on'
        end

        % Button pushed function: CancelButton_2
        function CancelButton_2Pushed(app, event)
             app.AboutPanel.Visible = 'off'
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FlightDynamicsEducationUIFigure = uifigure('Visible', 'off');
            app.FlightDynamicsEducationUIFigure.Name = 'Flight Dynamics Education';
            app.Menu = uimenu(app.FlightDynamicsEducationUIFigure);
            app.Menu.Text = 'Menu';
            app.TrimCurveMenu = uimenu(app.Menu);
            app.TrimCurveMenu.MenuSelectedFcn = createCallbackFcn(app, @TrimCurveMenuSelected, true);
            app.TrimCurveMenu.Text = 'Trim Curve';
            app.AboutMenu = uimenu(app.FlightDynamicsEducationUIFigure);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.MainPanel = uipanel(app.FlightDynamicsEducationUIFigure);
            app.Image = uiimage(app.MainPanel);
            app.Image.ImageSource = 'first background.jpg';
            app.TrimcurvePanel = uipanel(app.MainPanel);
            app.TrimcurvePanel.Title = 'Trim curve';
            app.TrimcurvePanel.Visible = 'off';
            app.GridLayout = uigridlayout(app.TrimcurvePanel);
            app.ConfirmButton = uibutton(app.GridLayout, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.Cm0Label = uilabel(app.GridLayout);
            app.Cm0Label.Text = 'Cm0:';
            app.Cm0EditField = uieditfield(app.GridLayout, 'numeric');
            app.Cm0EditField.Value = 0.1745;
            app.dCmdCLEditFieldLabel = uilabel(app.GridLayout);
            app.dCmdCLEditFieldLabel.Text = 'dCm/dCL';
            app.dCmdCLEditField = uieditfield(app.GridLayout, 'numeric');
            app.dCmdCLEditField.Value = -0.15;
            app.MinDElevatorEditField_2Label = uilabel(app.GridLayout);
            app.MinDElevatorEditField_2Label.Text = 'Min D Elevator';
            app.MinDElevatorEditField_2 = uieditfield(app.GridLayout, 'numeric');
            app.MinDElevatorEditField_2.Value = -15;
            app.MaxDElevatorEditFieldLabel = uilabel(app.GridLayout);
            app.MaxDElevatorEditFieldLabel.Text = 'Max D Elevator';
            app.MaxDElevatorEditField = uieditfield(app.GridLayout, 'numeric');
            app.MaxDElevatorEditField.Value = 20;
            app.NumberofStepEditFieldLabel = uilabel(app.GridLayout);
            app.NumberofStepEditFieldLabel.Text = 'Number of Step';
            app.NumberofStepEditField = uieditfield(app.GridLayout, 'numeric');
            app.NumberofStepEditField.Value = 5;
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.XcgEditFieldLabel = uilabel(app.GridLayout);
            app.XcgEditFieldLabel.Text = 'X c.g';
            app.XcgEditField = uieditfield(app.GridLayout, 'numeric');
            app.XcgEditField.Value = 0.25;
            app.CmDElevatorEditFieldLabel = uilabel(app.GridLayout);
            app.CmDElevatorEditFieldLabel.Text = 'Cm D Elevator';
            app.CmDElevatorEditField = uieditfield(app.GridLayout, 'numeric');
            app.CmDElevatorEditField.Value = -0.03;
            app.MaximumCLEditFieldLabel = uilabel(app.GridLayout);
            app.MaximumCLEditFieldLabel.Text = 'Maximum CL';
            app.MaximumCLEditField = uieditfield(app.GridLayout, 'numeric');
            app.MaximumCLEditField.Value = 1.23;
            app.TrimPloterPanel = uipanel(app.MainPanel);
            app.TrimPloterPanel.Title = 'TrimPloter';
            app.TrimPloterPanel.Visible = 'off';
            app.FinishButton = uibutton(app.TrimPloterPanel, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.FinishButton.Text = 'Finish';
            app.UIAxes = uiaxes(app.TrimPloterPanel);
            title(app.UIAxes, 'Trime Curve')
            xlabel(app.UIAxes, 'Pitching Moment Coefficient')
            ylabel(app.UIAxes, 'Lift Coefficent')
            app.Image2 = uiimage(app.TrimPloterPanel);
            app.Image2.ImageSource = 'Untitled.png';
            app.AerodynamicsCenterEditFieldLabel = uilabel(app.TrimPloterPanel);
            app.AerodynamicsCenterEditFieldLabel.Text = 'Aerodynamics Center';
            app.AerodynamicsCenterEditField = uieditfield(app.TrimPloterPanel, 'text');
            app.AerodynamicsCenterEditField.Editable = 'off';
            app.PresentCenterofGravityEditFieldLabel = uilabel(app.TrimPloterPanel);
            app.PresentCenterofGravityEditFieldLabel.Text = 'Present Center of Gravity';
            app.PresentCenterofGravityEditField = uieditfield(app.TrimPloterPanel, 'text');
            app.PresentCenterofGravityEditField.Editable = 'off';
            app.PresentCenterofGravityEditField.Value = '10';
            app.Slider = uislider(app.TrimPloterPanel);
            app.Slider.Limits = [0 10];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.MinimumCGEditFieldLabel = uilabel(app.TrimPloterPanel);
            app.MinimumCGEditFieldLabel.Text = 'Minimum C.G';
            app.MinimumCGEditField = uieditfield(app.TrimPloterPanel, 'text');
            app.MinimumCGEditField.Editable = 'off';
            app.AboutPanel = uipanel(app.MainPanel);
            app.AboutPanel.Title = 'About';
            app.AboutPanel.Visible = 'off';
            app.TextArea = uitextarea(app.AboutPanel);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'This software is designed for Flight Dynamics Calss II in February 2021.'; ''; 'Theacher: Dr.Hadi Zare'; ''; 'Develper: Hmed jahantigh'; 'Email: n.hamedjahantigh@yahoo.com'; ''};
            app.CancelButton_2 = uibutton(app.AboutPanel, 'push');
            app.CancelButton_2.ButtonPushedFcn = createCallbackFcn(app, @CancelButton_2Pushed, true);
            app.CancelButton_2.Text = 'Cancel';
            % Show the figure after all components are created
            app.FlightDynamicsEducationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FlightDynamicsEducationUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FlightDynamicsEducationUIFigure)
        end
    end
end